classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        btn_run                         matlab.ui.control.Button
        Result                          matlab.ui.container.Panel
        windSpeed_graph                 matlab.ui.control.UIAxes
        precipitation_graph             matlab.ui.control.UIAxes
        humidity_graph                  matlab.ui.control.UIAxes
        temp_graph                      matlab.ui.control.UIAxes
        Input                           matlab.ui.container.Panel
        edit_endDate                    matlab.ui.control.EditField
        StartDateyyyymmddoptionalLabel  matlab.ui.control.Label
        edit_startDate                  matlab.ui.control.EditField
        StartDateyyyymmddEditFieldLabel  matlab.ui.control.Label
        edit_location                   matlab.ui.control.EditField
        LocationEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        base_url = "https://weather.visualcrossing.com/VisualCrossingWebServices/rest/services/timeline/"
        API_KEY = '3KAJKHWT3UEMRQWF2ABKVVVZE'
    end
    
    methods (Access = private)
        
        function weatherData = fetchWeatherData(app, location, start_date, end_date)
            try
                if ~isempty(end_date)
                    api_url = sprintf('%s%s/%s/%s?key=%s', app.base_url, location, start_date, end_date, app.API_KEY);
                elseif ~isempty(start_date)
                    api_url = sprintf('%s%s/%s?key=%s', app.base_url, location, start_date, app.API_KEY);
                else
                    api_url = sprintf('%s%s?key=%s', app.base_url, location, app.API_KEY);
                end
                weatherData = webread(api_url);
            catch ME
                % Handle any errors that occur during the HTTP request
                error('Error fetching data from the API: %s', ME.message);
            end
        end
        
        function stat_data = plot_weather_data(app, weather_data)
            days = weather_data.days;
            dates = datetime({days.datetime}, 'InputFormat', 'yyyy-MM-dd');
            temperatures = [days.temp];
            humidities = [days.humidity];
            precipitations = [days.precip];
            wind_speeds = [days.windspeed];
            
            max_temp = max(temperatures);
            min_temp = min(temperatures);
            avg_temp = mean(temperatures);
            
            max_humidity = max(humidities);
            min_humidity = min(humidities);
            avg_humidity = mean(humidities);
            
            max_precip = max(precipitations);
            min_precip = min(precipitations);
            avg_precip = mean(precipitations);
            
            max_wind = max(wind_speeds);
            min_wind = min(wind_speeds);
            avg_wind = mean(wind_speeds);
            
            cla(app.temp_graph);
            cla(app.humidity_graph);
            cla(app.precipitation_graph)
            cla(app.windSpeed_graph)
            % Temperature Plot
            temp_ax = app.temp_graph;
            plot(temp_ax, dates, temperatures, 'r-');
            title(temp_ax, 'Daily Temperatures');
            ylabel(temp_ax, 'Temperature (°C)');
            grid(temp_ax, "on");
            hold(temp_ax, 'on');
            temp_ax.YLim = [min_temp - 1, max_temp + 1];
            yline(temp_ax, min_temp,'--b',sprintf('Min Temp: %.2f°C', min_temp));
            yline(temp_ax, max_temp,'--r',sprintf('Max Temp: %.2f°C', max_temp));
            yline(temp_ax, avg_temp,'--k',sprintf('Avg Temp: %.2f°C', avg_temp));            
%             legend(temp_ax, 'Temperature', 'Max Temp', 'Min Temp', 'Avg Temp', 'Location', 'best


            % Humidity Plot
            humidity_ax = app.humidity_graph;
            plot(humidity_ax, dates, humidities, 'b-');
            title(humidity_ax, 'Daily Humidity');
            ylabel(humidity_ax, 'Humidity (%)');
            grid(humidity_ax, "on");
            hold(humidity_ax, 'on');
            humidity_ax.YLim = [min_humidity - 1, max_humidity + 1];
            yline(humidity_ax, min_humidity,'--b',sprintf('Min Humidity: %.2f%%', min_humidity));
            yline(humidity_ax, max_humidity,'--r',sprintf('Max Humidity: %.2f%%', max_humidity));
            yline(humidity_ax, avg_humidity,'--k',sprintf('Avg Humidity: %.2f%%', avg_humidity));
%             legend('Humidity', 'Max Humidity', 'Min Humidity', 'Avg Humidity', 'Location', 'best');
            
            
            % Precipitation Plot
            precipitation_ax = app.precipitation_graph;
            plot(precipitation_ax, dates, precipitations, 'g-');
            title(precipitation_ax, 'Daily Precipitation');
            ylabel(precipitation_ax, 'Precipitation (mm)');
            grid(precipitation_ax, "on");
            hold(precipitation_ax, 'on');
            precipitation_ax.YLim = [min_precip - 1, max_precip + 1];
            yline(precipitation_ax, min_precip,'--b',sprintf('Min Precipitation: %.2fmm', min_precip));
            yline(precipitation_ax, max_precip,'--r',sprintf('Max Precipitation: %.2fmm', max_precip));
            yline(precipitation_ax, avg_precip,'--k',sprintf('Avg Precipitation: %.2fmm', avg_precip));
%             legend('Precipitation', 'Max Precipitation', 'Min Precipitation', 'Avg Precipitation', 'Location', 'best');
            
            % Wind Speed Plot
            windSpeed_ax = app.windSpeed_graph;
            plot(windSpeed_ax, dates, wind_speeds, 'm-');
            title(windSpeed_ax, 'Daily Wind Speed');
            ylabel(windSpeed_ax, 'Wind Speed (km/h)');
            
            hold(windSpeed_ax, 'on');
            windSpeed_ax.YLim = [min_wind - 1, max_wind + 1];
            yline(windSpeed_ax, min_wind,'--b',sprintf('Min Wind Speed: %.2fkm/h', min_wind));
            yline(windSpeed_ax, max_wind,'--r',sprintf('Max Wind Speed: %.2fkm/h', max_wind));
            yline(windSpeed_ax, avg_wind,'--k',sprintf('Avg Wind Speed: %.2fkm/h', avg_wind));
%             legend('Wind Speed', 'Max Wind Speed', 'Min Wind Speed', 'Avg Wind Speed', 'Location', 'best');

            % Optionally, save the figure to a file
            % saveas(fig, 'weather_plot.png');
            
            % Optionally, display the figure
            % imshow('weather_plot.png');
            
            stat_data = {max_temp, min_temp, avg_temp, max_humidity, min_humidity, avg_humidity, max_precip, min_precip, avg_precip, max_wind, min_wind, avg_wind};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_run
        function btn_run_pushed(app, event)
            location = app.edit_location.Value;
            startDate = app.edit_startDate.Value;
            endDate = app.edit_endDate.Value;

            if isempty(location)
                errordlg('Location is required!', 'Error');
                return;
            end
            weatherData = fetchWeatherData(app, location, startDate, endDate);
            stat_data = plot_weather_data(app, weatherData);
            disp(stat_data)
%             app.list_stat.Items(end+1) = stat_data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Input = uipanel(app.UIFigure);
            app.Input.Title = 'Input';
            app.LocationEditFieldLabel = uilabel(app.Input);
            app.LocationEditFieldLabel.Text = 'Location';
            app.edit_location = uieditfield(app.Input, 'text');
            app.StartDateyyyymmddEditFieldLabel = uilabel(app.Input);
            app.StartDateyyyymmddEditFieldLabel.Text = 'Start Date(yyyy-mm-dd)';
            app.edit_startDate = uieditfield(app.Input, 'text');
            app.StartDateyyyymmddoptionalLabel = uilabel(app.Input);
            app.StartDateyyyymmddoptionalLabel.Text = 'Start Date(yyyy-mm-dd, optional)';
            app.edit_endDate = uieditfield(app.Input, 'text');
            app.Result = uipanel(app.UIFigure);
            app.Result.Title = 'Result';
            app.temp_graph = uiaxes(app.Result);
            title(app.temp_graph, 'Daily Temperatures')
            xlabel(app.temp_graph, 'timestamp')
            ylabel(app.temp_graph, 'Temperature (°C)')
            zlabel(app.temp_graph, 'Z')
            app.humidity_graph = uiaxes(app.Result);
            title(app.humidity_graph, 'Daily Humidity')
            xlabel(app.humidity_graph, 'timestamp')
            ylabel(app.humidity_graph, 'Humidity (%)')
            zlabel(app.humidity_graph, 'Z')
            app.precipitation_graph = uiaxes(app.Result);
            title(app.precipitation_graph, 'Daily Precipitation')
            xlabel(app.precipitation_graph, 'timestamp')
            ylabel(app.precipitation_graph, 'Precipitation (mm)')
            zlabel(app.precipitation_graph, 'Z')
            app.windSpeed_graph = uiaxes(app.Result);
            title(app.windSpeed_graph, 'Daily Wind Speed')
            xlabel(app.windSpeed_graph, 'timestamp')
            ylabel(app.windSpeed_graph, 'Wind speed (km/h)')
            zlabel(app.windSpeed_graph, 'Z')
            app.windSpeed_graph.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.btn_run = uibutton(app.UIFigure, 'push');
            app.btn_run.ButtonPushedFcn = createCallbackFcn(app, @btn_run_pushed, true);
            app.btn_run.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end